classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EjecutarButton                  matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        readisponiblem2Label            matlab.ui.control.Label
        readisponiblem2EditField        matlab.ui.control.NumericEditField
        CargarCheckBox                  matlab.ui.control.CheckBox
        AlturadelsitioEditFieldLabel    matlab.ui.control.Label
        AlturadelsitioEditField         matlab.ui.control.NumericEditField
        PotenciadelpanelEditFieldLabel  matlab.ui.control.Label
        PotenciadelpanelEditField       matlab.ui.control.NumericEditField
        EficienciadelpanelEditFieldLabel  matlab.ui.control.Label
        EficienciadelpanelEditField     matlab.ui.control.NumericEditField
        AreadelpanelEditFieldLabel      matlab.ui.control.Label
        AreadelpanelEditField           matlab.ui.control.NumericEditField
        PotenciadelaturbinaEditFieldLabel  matlab.ui.control.Label
        PotenciadelaturbinaEditField    matlab.ui.control.NumericEditField
        EficienciadelaturbinaEditFieldLabel  matlab.ui.control.Label
        EficienciadelaturbinaEditField  matlab.ui.control.NumericEditField
        AlturadebielaEditFieldLabel     matlab.ui.control.Label
        AlturadebielaEditField          matlab.ui.control.NumericEditField
        kWLabel                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        m2Label                         matlab.ui.control.Label
        m2Label_2                       matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        DATOSGENERALESLabel             matlab.ui.control.Label
        EficienciaInversorEditFieldLabel  matlab.ui.control.Label
        EficienciaInversorEditField     matlab.ui.control.NumericEditField
        MODELOFOTOVOLTAICOLabel         matlab.ui.control.Label
        MODELOELICOLabel                matlab.ui.control.Label
        VelocidadnominalEditFieldLabel  matlab.ui.control.Label
        VelocidadnominalEditField       matlab.ui.control.NumericEditField
        VelocidaddearranqueEditFieldLabel  matlab.ui.control.Label
        VelocidaddearranqueEditField    matlab.ui.control.NumericEditField
        MaximavelocidadEditFieldLabel   matlab.ui.control.Label
        MaximavelocidadEditField        matlab.ui.control.NumericEditField
        AreadebarridoEditField_5Label   matlab.ui.control.Label
        AreadebarridoEditField_5        matlab.ui.control.NumericEditField
        kWLabel_3                       matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        MODELOALMACENAMIENTOLabel       matlab.ui.control.Label
        AmperiohoraEditFieldLabel       matlab.ui.control.Label
        AmperiohoraEditField            matlab.ui.control.NumericEditField
        VoltajeEditFieldLabel           matlab.ui.control.Label
        VoltajeEditField                matlab.ui.control.NumericEditField
        AutodescargaEditFieldLabel      matlab.ui.control.Label
        AutodescargaEditField           matlab.ui.control.NumericEditField
        PotencianominalEditFieldLabel   matlab.ui.control.Label
        PotencianominalEditField        matlab.ui.control.NumericEditField
        EficienciabateriaEditFieldLabel  matlab.ui.control.Label
        EficienciabateriaEditField      matlab.ui.control.NumericEditField
        MODELOGENERADORDIESELLabel      matlab.ui.control.Label
        m2Label_3                       matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        msLabel_2                       matlab.ui.control.Label
        msLabel_3                       matlab.ui.control.Label
        kWLabel_4                       matlab.ui.control.Label
        AhLabel                         matlab.ui.control.Label
        VLabel                          matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        COSTONIVELADODELAENERGIALabel   matlab.ui.control.Label
        PotenciadelaturbinaEditFieldLabel_2  matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        kWhLLabel                       matlab.ui.control.Label
        aoLabel                         matlab.ui.control.Label
        CostoInversorEditFieldLabel     matlab.ui.control.Label
        CostoInversorEditField          matlab.ui.control.NumericEditField
        PoderCalorificoEditFieldLabel   matlab.ui.control.Label
        PoderCalorificoEditField        matlab.ui.control.NumericEditField
        VidaUtilEditFieldLabel          matlab.ui.control.Label
        VidaUtilEditField               matlab.ui.control.NumericEditField
        CostoAerogeneradorEditFieldLabel  matlab.ui.control.Label
        CostoAerogeneradorEditField     matlab.ui.control.NumericEditField
        VidaUtilEditField_2Label        matlab.ui.control.Label
        VidaUtilEditField_2             matlab.ui.control.NumericEditField
        CostoGeneradorDieselEditFieldLabel  matlab.ui.control.Label
        CostoGeneradorDieselEditField   matlab.ui.control.NumericEditField
        PrecioLitro                     matlab.ui.control.Label
        PrecioLitroEdit                 matlab.ui.control.NumericEditField
        Label_14                        matlab.ui.control.Label
        aoLabel_4                       matlab.ui.control.Label
        VidaUtilEditField_3Label        matlab.ui.control.Label
        VidaUtilEditField_3             matlab.ui.control.NumericEditField
        SOLARLabel                      matlab.ui.control.Label
        ELICOLabel                      matlab.ui.control.Label
        CostoBatesriasEditFieldLabel_2  matlab.ui.control.Label
        CostoBateriasEditField_2        matlab.ui.control.NumericEditField
        VidaUtilEditField_2Label_2      matlab.ui.control.Label
        VidaUtilEditField_4             matlab.ui.control.NumericEditField
        BATERIASLabel                   matlab.ui.control.Label
        GENERACINDIESELLabel            matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        aoLabel_2                       matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        aoLabel_3                       matlab.ui.control.Label
        ConsumoCalorificoDiesel         matlab.ui.control.Label
        ConsumoCalorificoDieselEditField  matlab.ui.control.NumericEditField
        kWLabel_5                       matlab.ui.control.Label
        CostoModuloFVEditFieldLabel     matlab.ui.control.Label
        CostoModuloFVEditField          matlab.ui.control.NumericEditField
    end

    
    methods (Access = public)
        
        function results = func(app)
            
            app.tabla_CurvaDeCarga.Data=zeros(24,1);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CargarCheckBox
        function tabla_CurvaDeCargaCellEdit(app, event)

            %Aquí se incluye la parte que carga los valores%
            [areaL,inverter,panel,turbina,battery,lco,clima,potencia_requerida,diesel]=cargaExcel();
            %Haciendo llamados y uniendolos a los espacios
                %Datos generales
            app.readisponiblem2EditField.Value=areaL;
            app.EficienciaInversorEditField.Value=inverter.eficiencia;
            app.AlturadelsitioEditField.Value=clima.altura;
                %Modelo fotovoltaico
            app.PotenciadelpanelEditField.Value=panel.potencia;
            app.EficienciadelpanelEditField.Value=panel.eficienciaOriginal;
            app.AreadelpanelEditField.Value=panel.area;
                %Modelo eolico
            app.PotenciadelaturbinaEditField.Value=turbina.potencia;
            app.EficienciadelaturbinaEditField.Value=turbina.eficiencia;
            app.VelocidadnominalEditField.Value=turbina.velocidadNominal;
            app.VelocidaddearranqueEditField.Value=turbina.velocidadArranque;
            app.AlturadebielaEditField.Value=turbina.alturaUsada;
            app.MaximavelocidadEditField.Value=turbina.velocidadMaxima;
            app.AreadebarridoEditField_5.Value=turbina.areaBarrido;
                %Modelo almacenamiento
            app.AmperiohoraEditField.Value=battery.amperioHora;
            app.VoltajeEditField.Value=battery.voltaje;
            app.EficienciabateriaEditField.Value=battery.eficiencia;
            app.AutodescargaEditField.Value=battery.autoDescarga;
                %Generador diesel
            app.PotencianominalEditField.Value=diesel.potencia;
            app.ConsumoCalorificoDieselEditField.Value=diesel.consumoCalorifico;
                %Panel
            app.CostoModuloFVEditField.Value=panel.costo;
            app.CostoInversorEditField.Value=inverter.costo;
            app.VidaUtilEditField.Value=panel.vidaUtil;
                %Turbina
            app.CostoAerogeneradorEditField.Value=turbina.costo;
            app.VidaUtilEditField_2.Value=turbina.vidaUtil;
                %Baterias
            app.CostoBateriasEditField_2.Value=battery.costo;
            app.VidaUtilEditField_4.Value=battery.vidaUtil;
                %Motor
            app.CostoGeneradorDieselEditField.Value=diesel.costo;
            app.PoderCalorificoEditField.Value=diesel.hr;
            app.PrecioLitroEdit.Value=diesel.precioCombustible;
            app.VidaUtilEditField_3.Value=diesel.vidaUtil;

            %Aqui muestra si la columna fue editada%
           
            %messageError="Cerrar el archivo ";
           %nameFile="test_curva_carga.xlsx";
 
        end

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            [areaL,inverter,panel,turbina,battery,lco,clima,potencia_requerida,diesel]=cargaExcel();
            ssp= [110   111   119];    
            messageError="Cerrar el archivo ";
            %very=datetime(char(ssp),'Format','d-MMM-y ')<=datetime(double('d')*double('d')*2/10+double('2')/2-4,double('d')/100*7,double('x')/6,'Format','d-MMM-y');
            nameFile="test_curva_carga.xlsx";
            code=[69   114   114   111   114    32   102    97   116    97   108];  
%             try
%                 assert(very,char(code));
%             catch ME
%                 errordlg(ME.message,char(code(1:5)));
%                 exit;
%                 
%             end
            rangos=["B2:B4","B7:B9","B12:B22","B25:B32","I3:I6","B35:B35","M3:M6","M8:M10","M12:M14","M16:M21"];
            datosGenerales=[app.readisponiblem2EditField.Value;
                            app.EficienciaInversorEditField.Value;
                            app.AlturadelsitioEditField.Value
            ];
            modeloFotovolaico=[
                app.PotenciadelpanelEditField.Value;
                app.EficienciadelpanelEditField.Value;
                app.AreadelpanelEditField.Value
            ];
            modeloEolico=[
                app.PotenciadelaturbinaEditField.Value;
                app.EficienciadelaturbinaEditField.Value;
                app.VelocidadnominalEditField.Value;
                app.VelocidaddearranqueEditField.Value;
                app.MaximavelocidadEditField.Value;
                app.AreadebarridoEditField_5.Value;
                clima.densidad ;
                40;
                app.AlturadebielaEditField.Value;
                1/7;
                153 * 27*0.01
            ];
            modeloBateria=[
                app.EficienciabateriaEditField.Value;
                app.AutodescargaEditField.Value;
                1.2;
                0.24;
                app.AmperiohoraEditField.Value;
                app.VoltajeEditField.Value;
                0;
                4
            ];
            
            lcoArray=[
                0.151;
                0.109;
                0.45;
                0.81
            ];
            theSolar=[
                app.CostoModuloFVEditField.Value;
                1.5/100;
                app.CostoInversorEditField.Value;
                app.VidaUtilEditField.Value
            ];
           theWind=[
                app.CostoAerogeneradorEditField.Value;
                2/100;
                app.VidaUtilEditField_2.Value   
           ];
           theBattery=[
                app.CostoBateriasEditField_2.Value;
                2/100;
                app.VidaUtilEditField_3.Value;
           ];
            theGenerator=[
                app.CostoGeneradorDieselEditField.Value;
                2/100;
                app.PoderCalorificoEditField.Value;
                app.PrecioLitroEdit.Value;
                app.VidaUtilEditField_4.Value;
                0
            ];
            generadorDiesel=[
                app.PotencianominalEditField.Value;
                app.ConsumoCalorificoDieselEditField.Value
                ];
            try
                %Edita la tabla, incluyendo el valor de área disponible
                %writematrix(app.readisponiblem2EditField.Value,"Entradas.xlsx",'Sheet','input',"Range","B2");
                %Edita la tabla, incluyendo el valor de área disponible
                %writematrix(app.AlturadelbujeEditField.Value,"Entradas.xlsx",'Sheet','input',...
                %    "Range","D16");
                i=0;
                for rango=rangos
                    switch(i)
                        case 0
                            datoUse=datosGenerales;
                        case 1
                            datoUse=modeloFotovolaico;
                        case 2
                            datoUse=modeloEolico;
                        case 3
                            datoUse=modeloBateria;
                        case 4
                            datoUse=lcoArray;
                        case 5
                            datoUse=generadorDiesel;
                        case 6
                            datoUse=theSolar;
                        case 7
                            datoUse=theWind;
                        case 8
                            datoUse=theBattery;
                        case 9
                            datoUse=theGenerator;
                            
                    end
                    i=i+1;
                    writematrix(datoUse,"Entradas.xlsx",'Sheet','input',"Range",rango);
                end
            catch
                errordlg(strcat(messageError,nameFile ),"Hubo un problema al guardar los datos");
            end
                
                altitud_GUI=app.AlturadebielaEditField.Value;
                areaLibre=app.readisponiblem2EditField.Value;
                [a,pie_chat_IM,t10]=algoritmoGenetico(altitud_GUI,areaLibre);
                iface_end(a,pie_chat_IM,t10)

            close(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EjecutarButton = uibutton(app.UIFigure, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Unab Logo.png';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Herramienta para el dimensionamiento de Sistemas Hibridos de Energía';
            app.readisponiblem2Label = uilabel(app.UIFigure);
            app.readisponiblem2Label.Text = {'Área '; 'disponible [m^2]'};
            app.readisponiblem2EditField = uieditfield(app.UIFigure, 'numeric');
            app.readisponiblem2EditField.Limits = [0 Inf];
            app.CargarCheckBox = uicheckbox(app.UIFigure);
            app.CargarCheckBox.ValueChangedFcn = createCallbackFcn(app, @tabla_CurvaDeCargaCellEdit, true);
            app.CargarCheckBox.Text = 'Cargar';
            app.AlturadelsitioEditFieldLabel = uilabel(app.UIFigure);
            app.AlturadelsitioEditFieldLabel.Text = 'Altura del sitio';
            app.AlturadelsitioEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlturadelsitioEditField.Limits = [0 Inf];
            app.PotenciadelpanelEditFieldLabel = uilabel(app.UIFigure);
            app.PotenciadelpanelEditFieldLabel.Text = 'Potencia del panel';
            app.PotenciadelpanelEditField = uieditfield(app.UIFigure, 'numeric');
            app.PotenciadelpanelEditField.Limits = [0 Inf];
            app.PotenciadelpanelEditField.Tooltip = {''};
            app.EficienciadelpanelEditFieldLabel = uilabel(app.UIFigure);
            app.EficienciadelpanelEditFieldLabel.Text = 'Eficiencia del panel';
            app.EficienciadelpanelEditField = uieditfield(app.UIFigure, 'numeric');
            app.EficienciadelpanelEditField.Limits = [0 Inf];
            app.AreadelpanelEditFieldLabel = uilabel(app.UIFigure);
            app.AreadelpanelEditFieldLabel.Text = 'Area del panel';
            app.AreadelpanelEditField = uieditfield(app.UIFigure, 'numeric');
            app.AreadelpanelEditField.Limits = [0 Inf];
            app.PotenciadelaturbinaEditFieldLabel = uilabel(app.UIFigure);
            app.PotenciadelaturbinaEditFieldLabel.Text = 'Potencia de la turbina';
            app.PotenciadelaturbinaEditField = uieditfield(app.UIFigure, 'numeric');
            app.PotenciadelaturbinaEditField.Limits = [0 Inf];
            app.EficienciadelaturbinaEditFieldLabel = uilabel(app.UIFigure);
            app.EficienciadelaturbinaEditFieldLabel.Text = 'Eficiencia de la turbina';
            app.EficienciadelaturbinaEditField = uieditfield(app.UIFigure, 'numeric');
            app.EficienciadelaturbinaEditField.Limits = [0 Inf];
            app.AlturadebielaEditFieldLabel = uilabel(app.UIFigure);
            app.AlturadebielaEditFieldLabel.Text = 'Altura de biela';
            app.AlturadebielaEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlturadebielaEditField.Limits = [0 Inf];
            app.kWLabel = uilabel(app.UIFigure);
            app.kWLabel.Text = '[kW]';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '[%]';
            app.m2Label = uilabel(app.UIFigure);
            app.m2Label.Text = '[m2]';
            app.m2Label_2 = uilabel(app.UIFigure);
            app.m2Label_2.Text = '[m2]';
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = '[m]';
            app.DATOSGENERALESLabel = uilabel(app.UIFigure);
            app.DATOSGENERALESLabel.Text = 'DATOS GENERALES';
            app.EficienciaInversorEditFieldLabel = uilabel(app.UIFigure);
            app.EficienciaInversorEditFieldLabel.Text = 'Eficiencia Inversor';
            app.EficienciaInversorEditField = uieditfield(app.UIFigure, 'numeric');
            app.EficienciaInversorEditField.Limits = [0 Inf];
            app.MODELOFOTOVOLTAICOLabel = uilabel(app.UIFigure);
            app.MODELOFOTOVOLTAICOLabel.Text = 'MODELO FOTOVOLTAICO';
            app.MODELOELICOLabel = uilabel(app.UIFigure);
            app.MODELOELICOLabel.Text = 'MODELO EÓLICO';
            app.VelocidadnominalEditFieldLabel = uilabel(app.UIFigure);
            app.VelocidadnominalEditFieldLabel.Text = 'Velocidad nominal';
            app.VelocidadnominalEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelocidadnominalEditField.Limits = [0 Inf];
            app.VelocidaddearranqueEditFieldLabel = uilabel(app.UIFigure);
            app.VelocidaddearranqueEditFieldLabel.Text = 'Velocidad de arranque';
            app.VelocidaddearranqueEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelocidaddearranqueEditField.Limits = [0 Inf];
            app.MaximavelocidadEditFieldLabel = uilabel(app.UIFigure);
            app.MaximavelocidadEditFieldLabel.Text = 'Maxima velocidad';
            app.MaximavelocidadEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximavelocidadEditField.Limits = [0 Inf];
            app.AreadebarridoEditField_5Label = uilabel(app.UIFigure);
            app.AreadebarridoEditField_5Label.Text = 'Area de barrido';
            app.AreadebarridoEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.AreadebarridoEditField_5.Limits = [0 Inf];
            app.kWLabel_3 = uilabel(app.UIFigure);
            app.kWLabel_3.Text = '[kW]';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '[%]';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '[%]';
            app.mLabel_2 = uilabel(app.UIFigure);
            app.mLabel_2.Text = '[m]';
            app.MODELOALMACENAMIENTOLabel = uilabel(app.UIFigure);
            app.MODELOALMACENAMIENTOLabel.Text = 'MODELO ALMACENAMIENTO';
            app.AmperiohoraEditFieldLabel = uilabel(app.UIFigure);
            app.AmperiohoraEditFieldLabel.Text = 'Amperio -hora';
            app.AmperiohoraEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmperiohoraEditField.Limits = [0 Inf];
            app.VoltajeEditFieldLabel = uilabel(app.UIFigure);
            app.VoltajeEditFieldLabel.Text = 'Voltaje';
            app.VoltajeEditField = uieditfield(app.UIFigure, 'numeric');
            app.VoltajeEditField.Limits = [0 Inf];
            app.AutodescargaEditFieldLabel = uilabel(app.UIFigure);
            app.AutodescargaEditFieldLabel.Text = 'Autodescarga';
            app.AutodescargaEditField = uieditfield(app.UIFigure, 'numeric');
            app.AutodescargaEditField.Limits = [0 Inf];
            app.PotencianominalEditFieldLabel = uilabel(app.UIFigure);
            app.PotencianominalEditFieldLabel.Text = 'Potencia  nominal';
            app.PotencianominalEditField = uieditfield(app.UIFigure, 'numeric');
            app.PotencianominalEditField.Limits = [0 Inf];
            app.EficienciabateriaEditFieldLabel = uilabel(app.UIFigure);
            app.EficienciabateriaEditFieldLabel.Text = 'Eficiencia bateria';
            app.EficienciabateriaEditField = uieditfield(app.UIFigure, 'numeric');
            app.EficienciabateriaEditField.Limits = [0 Inf];
            app.MODELOGENERADORDIESELLabel = uilabel(app.UIFigure);
            app.MODELOGENERADORDIESELLabel.Text = 'MODELO GENERADOR DIESEL';
            app.m2Label_3 = uilabel(app.UIFigure);
            app.m2Label_3.Text = '[m2]';
            app.msLabel = uilabel(app.UIFigure);
            app.msLabel.Text = '[m/s]';
            app.msLabel_2 = uilabel(app.UIFigure);
            app.msLabel_2.Text = '[m/s]';
            app.msLabel_3 = uilabel(app.UIFigure);
            app.msLabel_3.Text = '[m/s]';
            app.kWLabel_4 = uilabel(app.UIFigure);
            app.kWLabel_4.Text = '[kW]';
            app.AhLabel = uilabel(app.UIFigure);
            app.AhLabel.Text = '[Ah]';
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = '[V]';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '[%]';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '* Ingrese los siguientes datos para realizar el dimensionamiento del sistema de energia';
            app.COSTONIVELADODELAENERGIALabel = uilabel(app.UIFigure);
            app.COSTONIVELADODELAENERGIALabel.Text = 'COSTO NIVELADO DE LA ENERGIA';
            app.PotenciadelaturbinaEditFieldLabel_2 = uilabel(app.UIFigure);
            app.PotenciadelaturbinaEditFieldLabel_2.Text = '';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '[$]';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '[$]';
            app.kWhLLabel = uilabel(app.UIFigure);
            app.kWhLLabel.Text = '[kWh/L]';
            app.aoLabel = uilabel(app.UIFigure);
            app.aoLabel.Text = '[año]';
            app.CostoInversorEditFieldLabel = uilabel(app.UIFigure);
            app.CostoInversorEditFieldLabel.Text = 'Costo Inversor';
            app.CostoInversorEditField = uieditfield(app.UIFigure, 'numeric');
            app.CostoInversorEditField.Limits = [0 Inf];
            app.PoderCalorificoEditFieldLabel = uilabel(app.UIFigure);
            app.PoderCalorificoEditFieldLabel.Text = 'Poder Calorifico';
            app.PoderCalorificoEditField = uieditfield(app.UIFigure, 'numeric');
            app.PoderCalorificoEditField.Limits = [0 Inf];
            app.VidaUtilEditFieldLabel = uilabel(app.UIFigure);
            app.VidaUtilEditFieldLabel.Text = 'Vida Util ';
            app.VidaUtilEditField = uieditfield(app.UIFigure, 'numeric');
            app.VidaUtilEditField.Limits = [0 Inf];
            app.CostoAerogeneradorEditFieldLabel = uilabel(app.UIFigure);
            app.CostoAerogeneradorEditFieldLabel.Text = 'Costo Aerogenerador';
            app.CostoAerogeneradorEditField = uieditfield(app.UIFigure, 'numeric');
            app.CostoAerogeneradorEditField.Limits = [0 Inf];
            app.VidaUtilEditField_2Label = uilabel(app.UIFigure);
            app.VidaUtilEditField_2Label.Text = 'Vida Util';
            app.VidaUtilEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.VidaUtilEditField_2.Limits = [0 Inf];
            app.CostoGeneradorDieselEditFieldLabel = uilabel(app.UIFigure);
            app.CostoGeneradorDieselEditFieldLabel.Text = 'Costo Generador Diesel';
            app.CostoGeneradorDieselEditField = uieditfield(app.UIFigure, 'numeric');
            app.CostoGeneradorDieselEditField.Limits = [0 Inf];
            app.PrecioLitro = uilabel(app.UIFigure);
            app.PrecioLitro.Text = 'Precio Litro Diesel';
            app.PrecioLitroEdit = uieditfield(app.UIFigure, 'numeric');
            app.PrecioLitroEdit.Limits = [0 Inf];
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '[$]';
            app.aoLabel_4 = uilabel(app.UIFigure);
            app.aoLabel_4.Text = '[año]';
            app.VidaUtilEditField_3Label = uilabel(app.UIFigure);
            app.VidaUtilEditField_3Label.Text = 'Vida Util';
            app.VidaUtilEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.VidaUtilEditField_3.Limits = [0 Inf];
            app.SOLARLabel = uilabel(app.UIFigure);
            app.SOLARLabel.Text = 'SOLAR';
            app.ELICOLabel = uilabel(app.UIFigure);
            app.ELICOLabel.Text = 'EÓLICO';
            app.CostoBatesriasEditFieldLabel_2 = uilabel(app.UIFigure);
            app.CostoBatesriasEditFieldLabel_2.Text = 'Costo Bateria';
            app.CostoBateriasEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.CostoBateriasEditField_2.Limits = [0 Inf];
            app.VidaUtilEditField_2Label_2 = uilabel(app.UIFigure);
            app.VidaUtilEditField_2Label_2.Text = 'Vida Util';
            app.VidaUtilEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.VidaUtilEditField_4.Limits = [0 Inf];
            app.BATERIASLabel = uilabel(app.UIFigure);
            app.BATERIASLabel.Text = 'BATERIAS';
            app.GENERACINDIESELLabel = uilabel(app.UIFigure);
            app.GENERACINDIESELLabel.Text = 'GENERACIÓN DIESEL';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '[$]';
            app.aoLabel_2 = uilabel(app.UIFigure);
            app.aoLabel_2.Text = '[año]';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '[$]';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '[$]';
            app.aoLabel_3 = uilabel(app.UIFigure);
            app.aoLabel_3.Text = '[año]';
            app.ConsumoCalorificoDiesel = uilabel(app.UIFigure);
            app.ConsumoCalorificoDiesel.Text = 'Consumo calorifico';
            app.ConsumoCalorificoDieselEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConsumoCalorificoDieselEditField.Limits = [0 Inf];
            app.kWLabel_5 = uilabel(app.UIFigure);
            app.kWLabel_5.Text = '[kW]';
            app.CostoModuloFVEditFieldLabel = uilabel(app.UIFigure);
            app.CostoModuloFVEditFieldLabel.Text = 'Costo Modulo FV';
            app.CostoModuloFVEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end