classdef iface1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Image                  matlab.ui.control.Image
        Label                  matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        Label_3                matlab.ui.control.Label
        SiguienteButton        matlab.ui.control.Button
        Label_4                matlab.ui.control.Label
        CondicionesxlsxButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SiguienteButton
        function SiguienteButtonPushed(app, event)
            app1
            close (app.UIFigure)
        end

        % Button pushed function: CondicionesxlsxButton
        function CondicionesxlsxButtonPushed(app, event)
            try 
                winopen("./Condiciones.xlsx");
            catch ME
                try
                    system("libreoffice --calc Condiciones.xlsx");
                catch ME
                    msgbox(["Si usa macOS o no abre, de aviso al autor\n",ME]);
                     web("https://drive.google.com/drive/folders/17eBH8_i-BkV5-9oMsNK1YNiiaIs86AtW");
                end
                
            end
            
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Unab Logo.png';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'HERRAMIENTA PARA EL DIMENSIONAMIENTO '; 'DE SISTEMAS HIBRIDOS DE ENERGIA'};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'Esta herramienta le permite diseñar un sistema híbrido de energía de acuerdo con el'; 'criterio de evaluación del mínimo costo nivelado de la energía LCOE. '};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = {'Esta herramienta le entregará la configuración más óptima para suplir con su'; 'demanda energética, mostrando como resultado la cantidad de paneles solares,'; 'turbinas eólicas, la capacidad del generador Diesel y la cantidad de baterías'; 'utilizadas de almacenamiento.'};
            app.SiguienteButton = uibutton(app.UIFigure, 'push');
            app.SiguienteButton.ButtonPushedFcn = createCallbackFcn(app, @SiguienteButtonPushed, true);
            app.SiguienteButton.Text = 'Siguiente';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = {'Descargue el siguiente archivo Condiciones.xlsx, y complete cada uno de los campos con '; 'la información requerida, guarde, cierre el archivo y pulse en siguiente.'; ' '};
            app.CondicionesxlsxButton = uibutton(app.UIFigure, 'push');
            app.CondicionesxlsxButton.ButtonPushedFcn = createCallbackFcn(app, @CondicionesxlsxButtonPushed, true);
            app.CondicionesxlsxButton.Text = 'Condiciones.xlsx';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = iface1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end